classdef Gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EpilepsyDetectionLabel          matlab.ui.control.Label
        NetworkSelectionPanel           matlab.ui.container.Panel
        SelectpredefinednetworkLabel    matlab.ui.control.Label
        DropDownPreDefined              matlab.ui.control.DropDown
        TestButtonPreDefined            matlab.ui.control.Button
        CreateownnetworkLabel           matlab.ui.control.Label
        DropDownType                    matlab.ui.control.DropDown
        SelectnetworktypeLabel          matlab.ui.control.Label
        DropDownTrainingSet             matlab.ui.control.DropDown
        SelecttrainingsetLabel          matlab.ui.control.Label
        DropDownPreference              matlab.ui.control.DropDown
        SelectpreferenceLabel           matlab.ui.control.Label
        TestButtonOwn                   matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        SpecializationLabel             matlab.ui.control.Label
        DropDownSpecialization          matlab.ui.control.DropDown
        DropDownNeurons                 matlab.ui.control.DropDown
        HiddenneuronsLabel              matlab.ui.control.Label
        EvaluationPanel                 matlab.ui.container.Panel
        TruePositivesEditFieldLabel     matlab.ui.control.Label
        TruePositivesEditField          matlab.ui.control.EditField
        TrueNegativesEditFieldLabel     matlab.ui.control.Label
        TrueNegativesEditField          matlab.ui.control.EditField
        FalsePositivesEditFieldLabel    matlab.ui.control.Label
        FalsePositivesEditField         matlab.ui.control.EditField
        SpecificityRecallNoSeizEditFieldLabel  matlab.ui.control.Label
        SpecificityRecallNoSeizEditField  matlab.ui.control.EditField
        SensitivityRecallSeizuresEditFieldLabel  matlab.ui.control.Label
        SensitivityRecallSeizuresEditField  matlab.ui.control.EditField
        PrecisionSeizureEditFieldLabel  matlab.ui.control.Label
        PrecisionSeizureEditField       matlab.ui.control.EditField
        ResultsLabel                    matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        PostprocessingLabel             matlab.ui.control.Label
        DropDownPostProcessing          matlab.ui.control.DropDown
        FalseNegativesEditField_2Label  matlab.ui.control.Label
        FalseNegativesEditField         matlab.ui.control.EditField
        PrecisionNoSeizureEditFieldLabel  matlab.ui.control.Label
        PrecisionNoSeizureEditField     matlab.ui.control.EditField
        PredictionDetectionLabel        matlab.ui.control.Label
    end


    methods (Access = private)
    
        function analyzeResults(app, predictionBoost, detectionBoost)
            global testSet;
            global testTargetMatrix;
            
            global net;
            
            testResults = net(testSet);

            %specialize for prediction
            testResults(2,:) = testResults(2,:) * predictionBoost;
            
            %specialize for detection
            testResults(3,:) = testResults(3,:) * detectionBoost;
            
            for i=1:length(testResults)
                max = 1;
                for j=2:4
                    if(testResults(j,i)>=testResults(max,i))
                       max=j; 
                    end
                end
                testResults(:,i) = zeros(4,1);
                testResults(max,i)=1;
            end
            
            %post-processing
            postProc = app.DropDownPostProcessing.Value;
            if(strcmp(postProc, '10 relaxed'))
                %checks if following 5 out of the next 10 are the same as the actual
                %if not -> inter-ictal (as it is just positive and negative -> no difference)
                
                for i=1:length(testResults)-10
                    followingAreTheSame = 0;
                     for j=1:10
                         if(isequal(testResults(:,i),testResults(:,i+j)))
                             followingAreTheSame = followingAreTheSame+1;
                         end
                     end
                     
                     if(followingAreTheSame < 5)
                         testResults(:,i)=[1;0;0;0];
                     end
                 end
            else
                if(strcmp(postProc, 'None'))
                    
                else
                    %checks if following 5 or 10 are the same as the actual
                    %if all pre-ictal -> keep as pre-ictal
                    %if all ictal -> keep as ictal
                    %if following not the same -> inter-ictal (as it is just positive and negative -> no difference)
                    
                    checkFollowing = str2num(postProc(1:2));
                    for i=1:length(testResults)-checkFollowing
                        followingAreTheSame = 1;
                         for j=1:checkFollowing
                             if(isequal(testResults(:,i),testResults(:,i+j)))
                                 
                             else
                                 followingAreTheSame = 0;
                             end
                         end
                         
                         if(followingAreTheSame == 0)
                             testResults(:,i)=[1;0;0;0];
                         end
                     end 
                end                                   
            end            
                
            
            tpPrediction = 0;
            tnPrediction = 0;
            fnPrediction = 0;
            fpPrediction = 0;
            
            tpDetection = 0;
            tnDetection = 0;
            fnDetection = 0;
            fpDetection = 0;            
                        
            for i=1:length(testResults)
                %prediction
                if(testResults(2,i)==testTargetMatrix(2,i))
                   if(testResults(2,i)==1)
                       tpPrediction = tpPrediction+1;
                   else
                       tnPrediction = tnPrediction+1;
                   end
                else
                   if(testResults(2,i)==1)
                       fpPrediction = fpPrediction+1;
                   else
                       fnPrediction = fnPrediction+1;
                   end
                end
            
                %detection
                if(testResults(3,i)==testTargetMatrix(3,i))
                   if(testResults(3,i)==1)
                       tpDetection = tpDetection+1;
                   else
                       tnDetection = tnDetection+1;
                   end
                else
                   if(testResults(3,i)==1)
                       fpDetection = fpDetection+1;
                   else
                       fnDetection = fnDetection+1;
                   end
                end
            end
            
            %sensitivity - how many true seizures did it predict  - should be high
            %               if we have a seizure, how sure can we be to detect it
            %               it is really bad if fn occur (a real seizure would not be
            %               detected)
            %recall of seizures...
            sensPrediction = tpPrediction/(tpPrediction+fnPrediction);
            sensDetection  = tpDetection /(tpDetection +fnDetection);
            
            %specificity - how many no seizures did it predict
            %               if we do not have a seizure, how sure can we be that we
            %               will not predict a seizure
            %               it is not bad if we have false alarms, but it should not be
            %               too bad
            %recall for no seizures
            specPrediction = tnPrediction/(tnPrediction+fpPrediction);
            specDetection  = tnDetection /(tnDetection +fpDetection);
            
            %precision of seizures - how many of the predicted seizures are seizures
            %               can be seen as false alarm probability
            %               does not need to be very high, but should not be very low
            precPrediction = tpPrediction/(tpPrediction+fpPrediction);
            precDetection  = tpDetection/ (tpDetection +fpDetection);
            
            %precision of no seizure - should be high
            %               how sure can we be that we have really no seizure if we
            %               predict no seizure
            precPredictionNoSeizure = tnPrediction/(tnPrediction+fnPrediction);
            precDetectionNoSeizure  = tnDetection/ (tnDetection +fnDetection);
            
            app.TruePositivesEditField.Value = strcat(num2str(tpPrediction),' | ',num2str(tpDetection));
            app.FalsePositivesEditField.Value = strcat(num2str(fpPrediction),' | ',num2str(fpDetection));
            app.TrueNegativesEditField.Value = strcat(num2str(tnPrediction),' | ',num2str(tnDetection));
            app.FalseNegativesEditField.Value = strcat(num2str(fnPrediction),' | ',num2str(fnDetection));
            
            app.SpecificityRecallNoSeizEditField.Value = strcat(num2str(specPrediction),' | ',num2str(specDetection));
            app.SensitivityRecallSeizuresEditField.Value = strcat(num2str(sensPrediction),' | ',num2str(sensDetection));
            app.PrecisionSeizureEditField.Value = strcat(num2str(precPrediction),' | ',num2str(precDetection));
            app.PrecisionNoSeizureEditField.Value = strcat(num2str(precPredictionNoSeizure),' | ',num2str(precDetectionNoSeizure));
        end
        
        function [trainingSet,trainingTargetMatrix, trainingIndexes, testIndexes] = build_sets(app, trainPercentage, unbalance)
            % trainPercentage - percentage of seizures that will be on the trainingset
            % unbalance - how many more interictal points will there be compared to the seizure points
            
            % equilibrate the nº of points of the several classes in the training set
            dataset = load('G4/112502.mat');
            TRG = dataset.Trg;
            dataset = dataset.FeatVectSel;
        
            targetVector = load('targetVector.mat');
            targetVector = targetVector.targetVector;
        
            targetMatrix = load('targetMatrix.mat');
            targetMatrix = targetMatrix.targetMatrix;
        
        
            seizures = diff(TRG);
            start_seizures = find(seizures == 1)-600;          % indexes of the 1st preictal ('2') of every seizure
            end_seizures = find(seizures == -1)+301;             % indexes of the last postictal ('4') of every seizure
            
            
            % -- BUILD TRAINING SET --
            total_seizures = length(start_seizures);                 % total nº of seizures in the dataset
            n_seizures_training = round(trainPercentage*total_seizures);         % 70% of seizures will be in the training set
            
            
            seizureIndexes = [];
            % indexes of all 2,3 and 4 points for training
            for i=1:n_seizures_training
                seizureIndexes = [seizureIndexes start_seizures(i):1:end_seizures(i)];
            end
        
            % Now we've got all the seizure points necessary for the dataset
            % Now for the interictal (normal) points
            % Nº is at most equal to the sum of the points of the seizure
            totalNormalPoints = length(seizureIndexes)*unbalance; % unbalance needs to have more 
            if (totalNormalPoints > length(dataset)-length(seizureIndexes))
                totalNormalPoints = length(dataset)-length(seizureIndexes);
            end
            
            % (make it random)
            %half = totalSeizurePoints/2;
            %totalNormalPoints = randi([half, totalSeizurePoints],1,1);
        
            normalIndexes = find(targetVector == 1)';
            normalIndexes = normalIndexes(1:totalNormalPoints);
        
            %join normal and seizure points indexes = all the training set
            trainingIndexes = horzcat(normalIndexes,seizureIndexes);
            trainingIndexes = sort(trainingIndexes);
        
            %Training and target set
            trainingSet = dataset(trainingIndexes,:)';
            trainingTargetMatrix = targetMatrix(trainingIndexes,:)';
        
            %remaining points (30% seizures) will be used for testing
            allIndexes = 1:length(dataset);
            testIndexes = setdiff(allIndexes,trainingIndexes);
        
            testSet = dataset(testIndexes,:)';
            testTargetMatrix = targetMatrix(testIndexes,:)';
        end
        
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global trainingSet;
            global trainingSet2;
            global trainingSet3;
            global trainingTargetMatrix;
            global trainingTargetMatrix2;
            global trainingTargetMatrix3;
            
            global testSet;
            global testTargetMatrix;
            
            global net;
            
            load('G4/112502.mat');
            createTargetMatrix;
            generateSets;
            
        end

        % Button pushed function: TestButtonPreDefined
        function TestButtonPreDefinedPushed(app, event)
            global net;
            predictionBoost = 1;
            detectionBoost = 1;
            
            if(strcmp(app.DropDownPreDefined.Value,'Feed-Forward Backprob'))
                net = load('network1.mat');
                net = net.network1;
            end
            
            if(strcmp(app.DropDownPreDefined.Value,'Feed-Forward Backprob (prediction)'))
                net = load('network1.mat');
                net = net.network1;
                predictionBoost = 1.75;
            end
            
            if(strcmp(app.DropDownPreDefined.Value,'Feed-Forward Backprob (detection)'))
                net = load('network1.mat');
                net = net.network1;
                detectionBoost = 7.75;
            end
            
            if(strcmp(app.DropDownPreDefined.Value,'Patternnet'))
                net = load('patternnet.mat');
                net = net.patternnet;
            end
            
            if(strcmp(app.DropDownPreDefined.Value,'Patternnet (prediction)'))
                net = load('patternnet.mat');
                net = net.patternnet;
                predictionBoost = 1.865;
            end
            
            if(strcmp(app.DropDownPreDefined.Value,'Patternnet (detection)'))
                net = load('patternnet.mat');
                net = net.patternnet;
                detectionBoost = 9;
            end
            
            if(strcmp(app.DropDownPreDefined.Value,'Feed-forward distributed time delay'))
                net = load('network2.mat');
                net = net.network2;
            end
            
            if(strcmp(app.DropDownPreDefined.Value,'Feed-forward distributed time delay (prediction)'))
                net = load('network2.mat');
                net = net.network2;
                predictionBoost = 1.69;
            end
            
            if(strcmp(app.DropDownPreDefined.Value,'Feed-forward distributed time delay (detection)'))
                net = load('network2.mat');
                net = net.network2;
                detectionBoost = 7.79;
            end
                        
            if(strcmp(app.DropDownPreDefined.Value,'Feed-forward distributed time delay (detection no seizures)'))
                net = load('network2.mat');
                net = net.network2;
                detectionBoost = 7.69;
            end
            
            if(strcmp(app.DropDownPreDefined.Value,'Final (prediction)'))
                net = load('predictNet2_medium.mat');
                net = net.net;
            end
                        
            if(strcmp(app.DropDownPreDefined.Value,'Final (detection)'))
                net = load('detectNet2High.mat');
                net = net.net;
            end
            
            analyzeResults(app, predictionBoost, detectionBoost);
            
        end

        % Button pushed function: TestButtonOwn
        function TestButtonOwnPushed(app, event)
                        global net;
            dataset = load('G4/112502.mat');
            dataset = dataset.FeatVectSel;
        
            targetMatrix = load('targetMatrix.mat');
            targetMatrix = targetMatrix.targetMatrix;
            
            predictionBoost = 1;
            detectionBoost = 1;
            
            % choose training set
            if(strcmp(app.DropDownTrainingSet.Value,'Balanced'))
                [trainingSet,trainingTargetMatrix, trainingIndexes, testIndexes ] = build_sets(app, 0.7, 1);
            
            elseif(strcmp(app.DropDownTrainingSet.Value,'Few Seizures'))
                [trainingSet,trainingTargetMatrix, trainingIndexes, testIndexes] = build_sets(app, 0.1, 1);
            
            elseif(strcmp(app.DropDownTrainingSet.Value,'Unbalanced'))
                [trainingSet,trainingTargetMatrix, trainingIndexes, testIndexes] = build_sets(app, 0.7, 10);
            end
            
            % choose prediction or detection
            if(strcmp(app.DropDownPreference.Value,'Prediction'))
                preference = 'prediction';
            elseif(strcmp(app.DropDownPreference.Value,'Detection'))
                preference = 'detection';
            end
           
            
            % -- compute error weights --
            
            % the weight of the error is the nº of times there are
            % more interictal points than preictal | ictal points
            errorWeights = ones(1,length(trainingSet));
            
            indexesNormal = find(trainingTargetMatrix(1,:) == 1);
            indexesPreIctal = find(trainingTargetMatrix(2,:) == 1);
            indexesIctal = find(trainingTargetMatrix(3,:) == 1);
            %indexesPostIctal = find(targetMatrix(4,:) == 1); % never used
            
            % Specialization
            if (strcmp(preference, 'prediction')==1)
                
                if(strcmp(app.DropDownSpecialization.Value,'High'))
                    eWeight = (length(indexesNormal)/length(indexesPreIctal))*1.25;
                    errorWeights(indexesPreIctal) = eWeight;
                elseif (strcmp(app.DropDownSpecialization.Value,'Medium'))
                    % weight is equal to how many times the normal points are larger than preIctal
                    eWeight = (length(indexesNormal)/length(indexesPreIctal));
                    errorWeights(indexesPreIctal) = eWeight;
                elseif (strcmp(app.DropDownSpecialization.Value,'None'))
                    % preIctal points are worth as much as the others
                    errorWeights(indexesPreIctal) = 1;
                end
                
            elseif (strcmp(preference, 'detection')==1)
                if(strcmp(app.DropDownSpecialization.Value,'High'))
                    eWeight = (length(indexesNormal)/length(indexesIctal))*1.25;
                    errorWeights(indexesIctal) = eWeight;
                elseif (strcmp(app.DropDownSpecialization.Value,'Medium'))
                    eWeight = (length(indexesNormal)/length(indexesIctal));
                    errorWeights(indexesIctal) = eWeight;
                elseif (strcmp(app.DropDownSpecialization.Value,'Low'))
                    errorWeights(indexesIctal) = 1;
                end
            end
            
            neurons = str2num(app.DropDownNeurons.Value);
            % choose type of network
            if(strcmp(app.DropDownType.Value,'Feedforward'))
                % divide by index
            
                net = feedforwardnet(neurons);
                net.divideFcn = 'divideind';
                [net.divideParam.trainInd,~,~] = divideind(length(trainingSet),1:length(trainingSet),[],[]);
                
                net.trainParam.epochs= 1000;
                
                net = train(net, trainingSet, trainingTargetMatrix, [], [], errorWeights,'useGPU','yes','showResources','yes');
                
    
            elseif(strcmp(app.DropDownType.Value,'Recurrent'))
                
                net = layrecnet(1:2,neurons);
                net.divideFcn = 'divideind';
                [net.divideParam.trainInd,~,~] = divideind(length(trainingSet),1:length(trainingSet),[],[]);
                
                net.trainFcn = 'trainscg';
                net.trainParam.epochs= 1000;
                % with delays
                %trainingSet = tonndata(trainingSet,true,false);%con2seq(trainingSet);
                %trainingTargetMatrix = tonndata(trainingTargetMatrix,true,false);%con2seq(trainingTargetMatrix);
                %errorWeights = tonndata(errorWeights);
                
                
                %[Xs,Xi,Ai,Ts,EWs, ~] = preparets(net,trainingSet,trainingTargetMatrix, {}, errorWeights);
                %net = train(net,Xs,Ts,Xi,Ai, EWs); %,'useGPU','yes','showResources','yes'
                
                %net = configure(net,trainingSet,trainingTargetMatrix);
                %net = init(net);
                %view(net)
                
                net = train(net,trainingSet,trainingTargetMatrix, {}, {},errorWeights);
                
                %X = tonndata(trainingSet,true,false);
                %T = tonndata(trainingTargetMatrix,true,false);
                %trainFcn = 'trainscg';
                %inputDelays = 1:2;
                %feedbackDelays = 1:2;
                %net = narxnet(inputDelays,feedbackDelays,neurons,'closed',trainFcn);
                %net.divideFcn = 'divideind';
                %[net.divideParam.trainInd,~,~] = divideind(length(trainingSet),1:length(trainingSet),[],[]);
                
                %[x,xi,ai,t,ew] = preparets(net,X,{},T,errorWeights);
                
                % Train the Network
                %[net,tr] = train(net,x,t,xi,ai,ew); %,'useGPU','yes','showResources','yes'
                
            elseif(strcmp(app.DropDownType.Value,'Fitnet'))
                net = fitnet(neurons,'trainscg');
                net.divideFcn = 'divideind';
                [net.divideParam.trainInd,~,~] = divideind(length(trainingSet),1:length(trainingSet),[],[]);
                
                net = train(net, trainingSet, trainingTargetMatrix, [], [], errorWeights,'useGPU','yes','showResources','yes');
                
            elseif(strcmp(app.DropDownType.Value,'Final'))
                
                net = feedforwardnet([24 10]);
                net.divideFcn = 'divideind';
                [net.divideParam.trainInd,~,~] = divideind(length(trainingSet),1:length(trainingSet),[],[]);
                
                net.trainFcn = 'trainbr';
                net.trainParam.epochs= 2000;
                
                net = train(net, trainingSet, trainingTargetMatrix, [], [], errorWeights);
            end
            view(net)
            analyzeResults(app, predictionBoost, detectionBoost);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.EpilepsyDetectionLabel = uilabel(app.UIFigure);
            app.EpilepsyDetectionLabel.Text = 'Epilepsy Detection';
            app.NetworkSelectionPanel = uipanel(app.UIFigure);
            app.NetworkSelectionPanel.Title = 'Network Selection';
            app.SelectpredefinednetworkLabel = uilabel(app.NetworkSelectionPanel);
            app.SelectpredefinednetworkLabel.Text = 'Select predefined network';
            app.DropDownPreDefined = uidropdown(app.NetworkSelectionPanel);
            app.DropDownPreDefined.Items = {'Feed-Forward Backprob', 'Feed-Forward Backprob (prediction)', 'Feed-Forward Backprob (detection)', 'Patternnet', 'Patternnet (prediction)', 'Patternnet (detection)', 'Feed-forward distributed time delay', 'Feed-forward distributed time delay (prediction)', 'Feed-forward distributed time delay (detection)', 'Feed-forward distributed time delay (detection no seizures)', 'Final (prediction)', 'Final (detection)'};
            app.DropDownPreDefined.Value = 'Feed-Forward Backprob';
            app.TestButtonPreDefined = uibutton(app.NetworkSelectionPanel, 'push');
            app.TestButtonPreDefined.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPreDefinedPushed, true);
            app.TestButtonPreDefined.Text = 'Test';
            app.CreateownnetworkLabel = uilabel(app.NetworkSelectionPanel);
            app.CreateownnetworkLabel.Text = 'Create own network';
            app.DropDownType = uidropdown(app.NetworkSelectionPanel);
            app.DropDownType.Items = {'Feedforward', 'Recurrent', 'Fitnet'};
            app.DropDownType.Value = 'Feedforward';
            app.SelectnetworktypeLabel = uilabel(app.NetworkSelectionPanel);
            app.SelectnetworktypeLabel.Text = 'Select network type';
            app.DropDownTrainingSet = uidropdown(app.NetworkSelectionPanel);
            app.DropDownTrainingSet.Items = {'Balanced', 'Unbalanced', 'Few Seizures'};
            app.DropDownTrainingSet.Value = 'Balanced';
            app.SelecttrainingsetLabel = uilabel(app.NetworkSelectionPanel);
            app.SelecttrainingsetLabel.Text = 'Select training set';
            app.DropDownPreference = uidropdown(app.NetworkSelectionPanel);
            app.DropDownPreference.Items = {'Prediction', 'Detection'};
            app.DropDownPreference.Value = 'Prediction';
            app.SelectpreferenceLabel = uilabel(app.NetworkSelectionPanel);
            app.SelectpreferenceLabel.Text = 'Select preference';
            app.TestButtonOwn = uibutton(app.NetworkSelectionPanel, 'push');
            app.TestButtonOwn.ButtonPushedFcn = createCallbackFcn(app, @TestButtonOwnPushed, true);
            app.TestButtonOwn.Text = 'Test';
            app.Label = uilabel(app.NetworkSelectionPanel);
            app.Label.Text = '_ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _';
            app.SpecializationLabel = uilabel(app.NetworkSelectionPanel);
            app.SpecializationLabel.Text = 'Specialization';
            app.DropDownSpecialization = uidropdown(app.NetworkSelectionPanel);
            app.DropDownSpecialization.Items = {'High', 'Medium', 'None'};
            app.DropDownSpecialization.Value = 'Medium';
            app.DropDownNeurons = uidropdown(app.NetworkSelectionPanel);
            app.DropDownNeurons.Items = {'10', '20', '30'};
            app.DropDownNeurons.Value = '10';
            app.HiddenneuronsLabel = uilabel(app.NetworkSelectionPanel);
            app.HiddenneuronsLabel.Text = 'Hidden neurons';
            app.EvaluationPanel = uipanel(app.UIFigure);
            app.EvaluationPanel.Title = 'Evaluation';
            app.TruePositivesEditFieldLabel = uilabel(app.EvaluationPanel);
            app.TruePositivesEditFieldLabel.Text = 'True Positives';
            app.TruePositivesEditField = uieditfield(app.EvaluationPanel, 'text');
            app.TrueNegativesEditFieldLabel = uilabel(app.EvaluationPanel);
            app.TrueNegativesEditFieldLabel.Text = 'True Negatives';
            app.TrueNegativesEditField = uieditfield(app.EvaluationPanel, 'text');
            app.FalsePositivesEditFieldLabel = uilabel(app.EvaluationPanel);
            app.FalsePositivesEditFieldLabel.Text = 'False Positives';
            app.FalsePositivesEditField = uieditfield(app.EvaluationPanel, 'text');
            app.SpecificityRecallNoSeizEditFieldLabel = uilabel(app.EvaluationPanel);
            app.SpecificityRecallNoSeizEditFieldLabel.Text = 'Specificity (Recall NoSeiz.)';
            app.SpecificityRecallNoSeizEditField = uieditfield(app.EvaluationPanel, 'text');
            app.SensitivityRecallSeizuresEditFieldLabel = uilabel(app.EvaluationPanel);
            app.SensitivityRecallSeizuresEditFieldLabel.Text = 'Sensitivity (Recall Seizures)';
            app.SensitivityRecallSeizuresEditField = uieditfield(app.EvaluationPanel, 'text');
            app.PrecisionSeizureEditFieldLabel = uilabel(app.EvaluationPanel);
            app.PrecisionSeizureEditFieldLabel.Text = 'Precision (Seizure)';
            app.PrecisionSeizureEditField = uieditfield(app.EvaluationPanel, 'text');
            app.ResultsLabel = uilabel(app.EvaluationPanel);
            app.ResultsLabel.Text = 'Results';
            app.Label_2 = uilabel(app.EvaluationPanel);
            app.Label_2.Text = '_ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ ';
            app.PostprocessingLabel = uilabel(app.EvaluationPanel);
            app.PostprocessingLabel.Text = 'Postprocessing';
            app.DropDownPostProcessing = uidropdown(app.EvaluationPanel);
            app.DropDownPostProcessing.Items = {'None', '5 strict', '10 strict', '15 strict', '10 relaxed'};
            app.DropDownPostProcessing.Value = 'None';
            app.FalseNegativesEditField_2Label = uilabel(app.EvaluationPanel);
            app.FalseNegativesEditField_2Label.Text = 'False Negatives';
            app.FalseNegativesEditField = uieditfield(app.EvaluationPanel, 'text');
            app.PrecisionNoSeizureEditFieldLabel = uilabel(app.EvaluationPanel);
            app.PrecisionNoSeizureEditFieldLabel.Text = 'Precision (No Seizure)';
            app.PrecisionNoSeizureEditField = uieditfield(app.EvaluationPanel, 'text');
            app.PredictionDetectionLabel = uilabel(app.EvaluationPanel);
            app.PredictionDetectionLabel.Text = 'Prediction | Detection';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Gui()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end