classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Label           matlab.ui.control.Label
        Panel           matlab.ui.container.Panel
        Label2          matlab.ui.control.Label
        Label3          matlab.ui.control.Label
        Label4          matlab.ui.control.Label
        Button          matlab.ui.control.Button
        LabelDropDown   matlab.ui.control.Label
        DropDown        matlab.ui.control.DropDown
        LabelDropDown2  matlab.ui.control.Label
        DropDown2       matlab.ui.control.DropDown
        Label5          matlab.ui.control.Label
        DropDown3       matlab.ui.control.DropDown
        Panel2          matlab.ui.container.Panel
        Button3         matlab.ui.control.Button
        Button4         matlab.ui.control.Button
        TextArea4       matlab.ui.control.TextArea
        Panel3          matlab.ui.container.Panel
        Label9          matlab.ui.control.Label
        TextArea3       matlab.ui.control.TextArea
        Label8          matlab.ui.control.Label
        TextArea2       matlab.ui.control.TextArea
        LabelTextArea   matlab.ui.control.Label
        TextArea        matlab.ui.control.TextArea
        Button2         matlab.ui.control.Button
    end

    methods (Access = private)
        function  createNetwork(app,trainSet,approach,activationFunction)
           
           data = load('dataset.mat');
           dataset = data.dataset;
           
           load('dataset2.mat');
           load('datasetTarget.mat');
           load('Target.mat');
           load('bigDataSet.mat');
           load('T.mat');
           
           divideSampleSize = 500;
                      
           if(strcmp(trainSet,'Bad Training Set'))
               x = dataset;
               t = datasetTarget;
           end
  
           if(strcmp(trainSet,'Good Training Set'))
               x = dataset2;
               t = datasetTarget;
           end
           
           if(strcmp(trainSet,'Combined Training Set'))
               x = bigDataSet;
               t = Target;
              divideSampleSize = 1000;
           end
           
            global net;            
           net = perceptron;

            net.performParam.lr = 0.5;
            net.trainParam.epochs = 1000;
            net.trainParam.show = 35;
            net.trainParam.goal = 1e-6;
            net.performFcn = 'mse';
            
            if(strcmp(activationFunction,'Purelin'))
                net.layers{1}.transferFcn = 'purelin';
                net.trainFcn = 'traingd';
           end
           
           if(strcmp(activationFunction,'Logsig'))
                net.layers{1}.transferFcn = 'logsig';
                net.trainFcn = 'traingd';
           end
           
           if(strcmp(activationFunction,'Hardlim'))
                net.layers{1}.transferFcn = 'hardlim';
                net.trainFcn = 'trainc';
           end
           
           [trainInd,valInd,testInd] = divideblock(divideSampleSize,0.85,0.15,0);
            net.divideFcn = 'divideblock';
            
            
            if(strcmp(approach,'Associative Memory + Classifier'))

                if(strcmp(trainSet,'Combined Training Set'))
                    WP = T * pinv(x);
                else
                    WP = T(:,1:500) * pinv(x);
                end 
                
                x = WP * x;    
            end
                        
            net = train(net,x,t);
            view(net);
        end
        
        function analyzeResults(app,print,res)
            testResults = res;
            
            classifiedResults = [];
            for i = 1:50
                max = 0;
                for j = 1:10
                   if testResults(j,i) > max
                    max = testResults(j,i);
                   end
                end
                
                for j = 1:10
                   if(testResults(j,i)== max)
                       classifiedResults = [classifiedResults,j];
                       break;
                   end
                end
            end
            
            trueDigits=0;
            correctMatrix = zeros(5,10);
            row = 1;
            for i = 1:50
               if i > 1
                   if mod (i,10) == 1
                       row = row + 1
                   end
               end
               should = mod(i,10);
               if should == mod(classifiedResults(i),10)
                   trueDigits = trueDigits + 1;
                   if(print==3)    
                       % inserts digits into the correctly classified matrix
                       col = should;
                       if col == 0
                           col = 10
                       end
                       should
                       row, col
                       correctMatrix(row, col)=1; % matrix of 0s and 1s(correctly classified)
                   end
               end
            end
            if(print==1)
                app.TextArea.Value = strcat(num2str(trueDigits),' out of 50 correct');
            elseif(print == 2)
                app.TextArea2.Value = strcat(num2str(trueDigits),' out of 50 correct');
            else
                app.TextArea3.Value = strcat(num2str(trueDigits),' out of 50 correct');
                drawCorrectGrid(app, correctMatrix);
            end
            

        end
        
        function drawCorrectGrid(app, correctMatrix)
            % function that draws the grid with the correctly classified numbers

            save('correctMatrix.mat', 'correctMatrix');
            
            correctMatrix
            figure(2);
            % plot the grid
            axis equal;
            axis([0 1 0 0.5]);
            hold on;
            % try %
            plot( [ 0 0 1 1 0 ], [ 0 .5 .5 0 0 ] );
            for i = 1:9, plot([i/10 i/10],[0 .5] ); end
            for i = 1:4, plot([0 1],[i/10 i/10] );  end
            
            
            % finds the indexes of all numbers that were correctly classified
            [row, col] = find(correctMatrix);
            
            % draws the numbers in the grid
            for i=1:length(col)
                character = num2str(mod(col(i),10));
                cWrite = text((col(i)-1)/10+0.05,(5-row(i)+1)/10-0.05, character);
                set(cWrite,'fontsize',25,'VerticalAlignment','middle',...
                    'HorizontalAlignment','center');
            end

        end
        
        function writeData(app)
            % function that writes the obtained data in an Excel File
        end
    end
   

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global net;
        end

        % Button pushed function: Button
        function TrainButtonPushed(app, event)
            trainSet = app.DropDown.Value;
            approach = app.DropDown2.Value;
            activationFunction = app.DropDown3.Value;
            
            createNetwork(app,trainSet,approach,activationFunction);
        end

        % Button pushed function: Button2
        function TestButtonPushed(app, event)
                       
            load('testSet.mat');
            load('testSet2.mat');
            load('P.mat');
            load('myPerfect.mat');
            load('T.mat');
            data = load('dataset.mat');
            dataset = data.dataset;
            load('dataset2.mat');
            load('bigDataSet.mat');
            global net;
            
            trainSet = app.DropDown.Value;
            
            if(strcmp(app.DropDown2.Value,'Associative Memory + Classifier'))
                
               if(strcmp(trainSet,'Bad Training Set'))
                   WP = myPerfect * pinv(dataset);
               elseif(strcmp(trainSet,'Good Training Set'))
                   WP = myPerfect * pinv(dataset2);
                else
                   WP = T * pinv(bigDataSet);
               end
           
                intResult = WP * testSet2.P;
                testResults = sim(net,intResult);
                analyzeResults(app,1,testResults);
            
                intResult = WP * testSet.P;
                testResults = sim(net,intResult);
                analyzeResults(app,2,testResults);
                
                intResult = WP * P;
                testResults = sim(net,intResult);
                analyzeResults(app,3,testResults);
                
            else
                testResults = sim(net,testSet2.P);
                analyzeResults(app,1,testResults);
                
                testResults = sim(net,testSet.P);
                analyzeResults(app,2,testResults);
                
                testResults = sim(net,P);
                analyzeResults(app,3,testResults);
            end
            
        end

        % Button pushed function: Button3
        function TestsetButtonPushed(app, event)
            mpaper;
        end

        % Button pushed function: Button4
        function ShowTestButtonPushed(app, event)
            load('P.mat');
            showim(P);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Neural Network Test Cockpit';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Training';
            app.Label2 = uilabel(app.Panel);
            app.Label2.Text = 'Select Training Set';
            app.Label3 = uilabel(app.Panel);
            app.Label3.Text = 'Select Approach';
            app.Label4 = uilabel(app.Panel);
            app.Label4.Text = 'Select Activation Function';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.Button.Text = 'Train';
            app.LabelDropDown = uilabel(app.Panel);
            app.LabelDropDown.Text = 'Drop Down';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'Combined Training Set', 'Good Training Set', 'Bad Training Set'};
            app.DropDown.Value = 'Combined Training Set';
            app.LabelDropDown2 = uilabel(app.Panel);
            app.LabelDropDown2.Text = 'Drop Down';
            app.DropDown2 = uidropdown(app.Panel);
            app.DropDown2.Items = {'Associative Memory + Classifier', 'Direct Classifier'};
            app.DropDown2.Value = 'Associative Memory + Classifier';
            app.Label5 = uilabel(app.Panel);
            app.Label5.Text = 'Drop Down';
            app.DropDown3 = uidropdown(app.Panel);
            app.DropDown3.Items = {'Hardlim', 'Purelin', 'Logsig'};
            app.DropDown3.Value = 'Hardlim';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Testset Creation';
            app.Button3 = uibutton(app.Panel2, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @TestsetButtonPushed, true);
            app.Button3.Text = 'Create Testset';
            app.Button4 = uibutton(app.Panel2, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @ShowTestButtonPushed, true);
            app.Button4.Text = 'Show Testset';
            app.TextArea4 = uitextarea(app.Panel2);
            app.TextArea4.Editable = 'off';
            app.TextArea4.Value = {'Your own testset '; 'needs to be called '; '"P.mat" and be '; 'located in the same folder of the GUI. It must contain '; 'numbers in each '; 'field (start by 1), so '; 'that we can learn '; 'the weight matrix of the AM.'; 'You can use the '; 'buttons below to '; 'create and show it.'};
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'Testing';
            app.Label9 = uilabel(app.Panel3);
            app.Label9.Text = 'Test Results (Own Testset)';
            app.TextArea3 = uitextarea(app.Panel3);
            app.Label8 = uilabel(app.Panel3);
            app.Label8.Text = 'Test Results (Bad Testset)';
            app.TextArea2 = uitextarea(app.Panel3);
            app.LabelTextArea = uilabel(app.Panel3);
            app.LabelTextArea.Text = 'Test Results (Good Testset)';
            app.TextArea = uitextarea(app.Panel3);
            app.Button2 = uibutton(app.Panel3, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.Button2.Text = 'Test';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end